classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AnalyzeButton              matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        ApplyFiltersButton         matlab.ui.control.Button
        HzSlider_9                 matlab.ui.control.Slider
        KHzLabel_3                 matlab.ui.control.Label
        slider_disp_9              matlab.ui.control.Label
        HzSlider_8                 matlab.ui.control.Slider
        KHzSlider_3Label           matlab.ui.control.Label
        HzSlider_7                 matlab.ui.control.Slider
        KHzLabel                   matlab.ui.control.Label
        HzSlider_6                 matlab.ui.control.Slider
        KHzLabel_2                 matlab.ui.control.Label
        HzSlider_5                 matlab.ui.control.Slider
        Hz3KHzLabel                matlab.ui.control.Label
        slider_disp_8              matlab.ui.control.Label
        slider_disp_7              matlab.ui.control.Label
        slider_disp_6              matlab.ui.control.Label
        slider_disp_5              matlab.ui.control.Label
        HzSlider_4                 matlab.ui.control.Slider
        HzLabel_4                  matlab.ui.control.Label
        slider_disp_4              matlab.ui.control.Label
        HzSlider_3                 matlab.ui.control.Slider
        HzLabel_3                  matlab.ui.control.Label
        slider_disp_3              matlab.ui.control.Label
        HzSlider_2                 matlab.ui.control.Slider
        HzLabel_2                  matlab.ui.control.Label
        slider_disp_2              matlab.ui.control.Label
        slider_disp_1              matlab.ui.control.Label
        FilterDropDown             matlab.ui.control.DropDown
        FilterDropDownLabel        matlab.ui.control.Label
        HzSlider                   matlab.ui.control.Slider
        HzLabel                    matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        samplerateEditField        matlab.ui.control.NumericEditField
        samplerateEditFieldLabel   matlab.ui.control.Label
        stop_Button                matlab.ui.control.Button
        play_Button                matlab.ui.control.Button
        browseButton               matlab.ui.control.Button
        fileaddressEditField       matlab.ui.control.EditField
        fileaddressEditFieldLabel  matlab.ui.control.Label
        enhancedPlot               matlab.ui.control.UIAxes
        originalPlot               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filename;
        pathname;
        fs=0;
        fs_original=0;
        audio=0;
        audio_old=0;
        gainsArr = ones(1, 9);
    end
    
    methods (Access = private)
        
        
        function apply(app)
            
            app.audio = apply_filter(app.FilterDropDown.Value, app.gainsArr, app.audio_old, app.fs_original);
            
            app.draw();
        end
        
        function draw(app)
            plot(app.originalPlot, app.audio_old);
            plot(app.enhancedPlot, app.audio);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            [file, Pathname] = uigetfile('*.wav','wav file selector');
            app.filename = file;
            app.pathname = strcat(file, Pathname);
            [app.audio, app.fs] = audioread(file);
            app.audio_old = app.audio;
            app.fs_original=app.fs;
            
            app.fileaddressEditField.Value = app.filename;
            app.samplerateEditField.Value = app.fs;
            
            app.draw();
        end

        % Button pushed function: play_Button
        function play_ButtonPushed(app, event)
            clear sound
            sound(app.audio, app.fs);
        end

        % Button pushed function: stop_Button
        function stop_ButtonPushed(app, event)
            clear sound;
        end

        % Value changing function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(2) = value;
            app.slider_disp_2.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Value changing function: HzSlider
        function HzSliderValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(1) = value;
            app.slider_disp_1.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Value changing function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(3) = value;
            app.slider_disp_3.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Value changing function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(4) = value;
            app.slider_disp_4.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Value changing function: HzSlider_5
        function HzSlider_5ValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(5) = value;
            app.slider_disp_5.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Value changing function: HzSlider_6
        function HzSlider_6ValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(6) = value;
            app.slider_disp_6.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Value changing function: HzSlider_7
        function HzSlider_7ValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(7) = value;
            app.slider_disp_7.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Value changing function: HzSlider_8
        function HzSlider_8ValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(8) = value;
            app.slider_disp_8.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Value changing function: HzSlider_9
        function HzSlider_9ValueChanged(app, event)
            value = 10^(event.Value / 20);    
            app.gainsArr(9) = value;
            app.slider_disp_9.Text = mat2str(value, 2);
            
            app.ApplyFiltersButton.Enable = 1;
        end

        % Button pushed function: ApplyFiltersButton
        function ApplyFiltersButtonPushed(app, event)
            app.ApplyFiltersButton.Enable = 0;
            
            app.apply(); % slow function
            
            clear sound;
            sound(app.audio, app.fs);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite('enhanced.wav', app.audio, app.fs);
        end

        % Value changed function: samplerateEditField
        function samplerateEditFieldValueChanged2(app, event)
              value = app.samplerateEditField.Value;
                if value == app.fs
                    app.audio = app.audio_old;
                elseif value > app.fs
                    app.audio = upsample(app.audio_old, value/app.fs);
                    app.fs=value;
                    app.draw
                elseif value < app.fs
                    downsamplingFactor = floor(app.fs/value); % Round down to nearest integer
                    app.audio = downsample(app.audio_old, downsamplingFactor);
                    app.fs=value;
                    app.draw
                end
    
                app.ApplyFiltersButton.Enable = 1;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            analyze_signal(app.fs, app.FilterDropDown.Value, app.audio);
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            app.ApplyFiltersButton.Enable = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.originalPlot = uiaxes(app.UIFigure);
            title(app.originalPlot, 'Original Audio')
            app.enhancedPlot = uiaxes(app.UIFigure);
            title(app.enhancedPlot, 'Enhanced Audio')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.fileaddressEditFieldLabel = uilabel(app.Panel);
            app.fileaddressEditFieldLabel.Text = 'file address';
            app.fileaddressEditField = uieditfield(app.Panel, 'text');
            app.fileaddressEditField.Placeholder = 'browse file';
            app.browseButton = uibutton(app.Panel, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.play_Button = uibutton(app.Panel, 'push');
            app.play_Button.ButtonPushedFcn = createCallbackFcn(app, @play_ButtonPushed, true);
            app.play_Button.Icon = 'OIP.jpeg';
            app.play_Button.Text = {'Play'; ''};
            app.stop_Button = uibutton(app.Panel, 'push');
            app.stop_Button.ButtonPushedFcn = createCallbackFcn(app, @stop_ButtonPushed, true);
            app.stop_Button.Icon = 'OIP (1).jpeg';
            app.stop_Button.Text = 'Stop';
            app.samplerateEditFieldLabel = uilabel(app.Panel);
            app.samplerateEditFieldLabel.Text = 'sample rate';
            app.samplerateEditField = uieditfield(app.Panel, 'numeric');
            app.samplerateEditField.ValueChangedFcn = createCallbackFcn(app, @samplerateEditFieldValueChanged2, true);
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '0 - 170 Hz';
            app.HzSlider = uislider(app.UIFigure);
            app.HzSlider.Limits = [-24 24];
            app.HzSlider.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider.ValueChangingFcn = createCallbackFcn(app, @HzSliderValueChanged, true);
            app.HzSlider.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'FIR', 'IIR'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Placeholder = '-';
            app.FilterDropDown.Value = 'FIR';
            app.slider_disp_1 = uilabel(app.UIFigure);
            app.slider_disp_1.Text = '0db';
            app.slider_disp_2 = uilabel(app.UIFigure);
            app.slider_disp_2.Text = '0db';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '170 - 300 Hz';
            app.HzSlider_2 = uislider(app.UIFigure);
            app.HzSlider_2.Limits = [-24 24];
            app.HzSlider_2.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider_2.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider_2.ValueChangingFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzSlider_2.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.slider_disp_3 = uilabel(app.UIFigure);
            app.slider_disp_3.Text = '0db';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = '300 - 610 Hz';
            app.HzSlider_3 = uislider(app.UIFigure);
            app.HzSlider_3.Limits = [-24 24];
            app.HzSlider_3.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider_3.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider_3.ValueChangingFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.HzSlider_3.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.slider_disp_4 = uilabel(app.UIFigure);
            app.slider_disp_4.Text = '0db';
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = ' 610 - 1005 Hz';
            app.HzSlider_4 = uislider(app.UIFigure);
            app.HzSlider_4.Limits = [-24 24];
            app.HzSlider_4.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider_4.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider_4.ValueChangingFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.HzSlider_4.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.slider_disp_5 = uilabel(app.UIFigure);
            app.slider_disp_5.Text = '0db';
            app.slider_disp_6 = uilabel(app.UIFigure);
            app.slider_disp_6.Text = '0db';
            app.slider_disp_7 = uilabel(app.UIFigure);
            app.slider_disp_7.Text = '0db';
            app.slider_disp_8 = uilabel(app.UIFigure);
            app.slider_disp_8.Text = '0db';
            app.Hz3KHzLabel = uilabel(app.UIFigure);
            app.Hz3KHzLabel.Text = '1005Hz - 3 KHz';
            app.HzSlider_5 = uislider(app.UIFigure);
            app.HzSlider_5.Limits = [-24 24];
            app.HzSlider_5.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider_5.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider_5.ValueChangingFcn = createCallbackFcn(app, @HzSlider_5ValueChanged, true);
            app.HzSlider_5.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.KHzLabel_2 = uilabel(app.UIFigure);
            app.KHzLabel_2.Text = '3 - 6 KHz';
            app.HzSlider_6 = uislider(app.UIFigure);
            app.HzSlider_6.Limits = [-24 24];
            app.HzSlider_6.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider_6.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider_6.ValueChangingFcn = createCallbackFcn(app, @HzSlider_6ValueChanged, true);
            app.HzSlider_6.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.KHzLabel = uilabel(app.UIFigure);
            app.KHzLabel.Text = '6 - 12 KHz';
            app.HzSlider_7 = uislider(app.UIFigure);
            app.HzSlider_7.Limits = [-24 24];
            app.HzSlider_7.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider_7.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider_7.ValueChangingFcn = createCallbackFcn(app, @HzSlider_7ValueChanged, true);
            app.HzSlider_7.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.KHzSlider_3Label = uilabel(app.UIFigure);
            app.KHzSlider_3Label.Text = ' 12-14 KHz';
            app.HzSlider_8 = uislider(app.UIFigure);
            app.HzSlider_8.Limits = [-24 24];
            app.HzSlider_8.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider_8.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider_8.ValueChangingFcn = createCallbackFcn(app, @HzSlider_8ValueChanged, true);
            app.HzSlider_8.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.slider_disp_9 = uilabel(app.UIFigure);
            app.slider_disp_9.Text = '0db';
            app.KHzLabel_3 = uilabel(app.UIFigure);
            app.KHzLabel_3.Text = ' 14-20 KHz';
            app.HzSlider_9 = uislider(app.UIFigure);
            app.HzSlider_9.Limits = [-24 24];
            app.HzSlider_9.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.HzSlider_9.MajorTickLabels = {'-24', '-18', '-12', '-6', '0', '6', '12', '18', '24'};
            app.HzSlider_9.ValueChangingFcn = createCallbackFcn(app, @HzSlider_9ValueChanged, true);
            app.HzSlider_9.MinorTicks = [-24 -22.5 -21 -19.5 -18 -16.5 -15 -13.5 -12 -10.5 -9 -7.5 -6 -4.5 -3 -1.5 0 1.5 3 4.5 6 7.5 9 10.5 12 13.5 15 16.5 18 19.5 21 22.5 24];
            app.ApplyFiltersButton = uibutton(app.UIFigure, 'push');
            app.ApplyFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFiltersButtonPushed, true);
            app.ApplyFiltersButton.Enable = 'off';
            app.ApplyFiltersButton.Text = 'Apply Filters';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end